classdef app_draft_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        OptionsTab                  matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        STARTSTOPACQUISITIONButton  matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        CalibrateButton             matlab.ui.control.Button
        OffsetButton                matlab.ui.control.Button
        Lamp_2                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Panel_2                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        ForceTab                    matlab.ui.container.Tab
        RotationTab                 matlab.ui.container.Tab
        EMGTab                      matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Status = 0 % 0 = Not connected; 1 = Connected
        tcpScoket 
        
        % Sampling frequency values
        Fsamp = [0 8 16 24] % Set the sampling frequency
        FsampVal = [512 2048 5120 10240]
        NumChan = [0 2 4 6]  % Codes to set the number of channels
        NumChanVal = [120 216 312 408]
        FSsel = 2 % FSsel  = 1 -> 512 Hz; FSsel  = 2 -> 2048 Hz; FSsel  = 3 -> 5120 Hz; FSsel  = 4 -> 10240 Hz
        nsamp = 128
        n_grids = 1 
        NCHsel = 4 % NCHsel = 1 -> IN1, IN2, MULTIPLE IN1, AUX IN; NCHsel = 2 -> IN1..IN4, MULTIPLE IN1, MULTIPLE IN2, AUX IN; NCHsel = 3 -> IN1..IN6, MULTIPLE IN1..MULTIPLE IN3, AUX IN;  NCHsel = 4 -> IN1..IN8, MULTIPLE IN1..MULTIPLE IN4, AUX IN
        
        EMG % Description
        TCPPort
        ConfString
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTSTOPACQUISITIONButton
        function STARTSTOPACQUISITIONButtonPushed(app, event)
            if app.Status == 1
                % Stop connection to OTB
                app.ConfString(1) = bin2dec('10000000');    % First byte that stop the data transfer
                app.ConfString(40) = CRC8(app.ConfString, 39);  % Estimates the new CRC
                fwrite(app.tcpScoket, app.ConfString, 'uint8');
                fclose(app.tcpScoket);
                app.Lamp.Color = [1 0 0];
                app.Status = 0;
            elseif app.Status == 0
                % Start connection to OTB
                %_______________OT QUATTROCENTO__________________
                offset = 5000; %32768;
                
                % Sampling frequency values
                app.TCPPort = 23456;
                GainFactor = 5/2^16/150*1000000;% Provide amplitude in mV
                
                % 5 is the ADC input swing
                % 2^16 is the resolution
                % 150 is the gain
                % 1000 to get the mV
                
                AuxGainFactor = 5/2^16/0.5;         % Gain factor to convert Aux Channels in V
                EmgGainFactor = 1000*5/2^16/0.5;
                
                % Confstring definition
                app.ConfString(1) = bin2dec('10000000') + 64 + app.Fsamp(app.FSsel) + app.NumChan(app.NCHsel) + 1;
                app.ConfString(2) = bin2dec('00000000') + 8;
                app.ConfString(3) = 0;
                % -------- IN 1 -------- %
                app.ConfString(4) = 0;
                app.ConfString(5) = 0;
                app.ConfString(6) = bin2dec('00010100');
                % -------- IN 2 -------- %
                app.ConfString(7) = 0;
                app.ConfString(8) = 0;
                app.ConfString(9) = bin2dec('00010100');
                % -------- IN 3 -------- %
                app.ConfString(10) = 0;
                app.ConfString(11) = 0;
                app.ConfString(12) = bin2dec('00010100');
                % -------- IN 4 -------- %
                app.ConfString(13) = 0;
                app.ConfString(14) = 0;
                app.ConfString(15) = bin2dec('00010100');
                % -------- IN 5 -------- %
                app.ConfString(16) = 0;
                app.ConfString(17) = 0;
                app.ConfString(18) = bin2dec('00010100');
                % -------- IN 6 -------- %
                app.ConfString(19) = 0;
                app.ConfString(20) = 0;
                app.ConfString(21) = bin2dec('00010100');
                % -------- IN 7 -------- %
                app.ConfString(22) = 0;
                app.ConfString(23) = 0;
                app.ConfString(24) = bin2dec('00010100');
                % -------- IN 8 -------- %
                app.ConfString(25) = 0;
                app.ConfString(26) = 0;
                app.ConfString(27) = bin2dec('00010100');
                % -------- MULTIPLE IN 1 -------- %
                app.ConfString(28) = 0;
                app.ConfString(29) = 0;
                app.ConfString(30) = bin2dec('00010100');
                % -------- MULTIPLE IN 2 -------- %
                app.ConfString(31) = 0;
                app.ConfString(32) = 0;
                app.ConfString(33) = bin2dec('00010100');
                % -------- MULTIPLE IN 3 -------- %
                app.ConfString(34) = 0;
                app.ConfString(35) = 0;
                app.ConfString(36) = bin2dec('00010100');
                % -------- MULTIPLE IN 4 -------- %
                app.ConfString(37) = 0;
                app.ConfString(38) = 0;
                app.ConfString(39) = bin2dec('00010100');
                % ---------- CRC8 ---------- %
                app.ConfString(40) = CRC8(app.ConfString, 39);
               
                
                % TCP socket definition
               app.tcpScoket = tcpip('169.254.1.10', app.TCPPort, 'NetworkRole', 'client');
               app.tcpScoket.InputBufferSize = 2*app.NumChanVal(app.NCHsel)*app.FsampVal(app.FSsel);

                try
                    fopen(app.tcpScoket);
                    set(app.tcpScoket, 'ByteOrder', 'littleEndian');
                catch
                    disp('Cannot connect');
                    app.Lamp.Color = [1 0 0];
                    app.Status = 0;
                end

                if strcmp(app.tcpScoket.Status,'open')
                    fwrite(app.tcpScoket, app.ConfString, 'uint8');
                    app.Lamp.Color = [0 1 0];
                    app.Status = 1;
                else
                    app.Lamp.Color = [1 0 0];
                    app.Status =0;
                end
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
             triallength = app.FsampVal(app.FSsel)*30; %Trial = 30s
             time = linspace(0,5,triallength);
             buffer = zeros(64*app.n_grids,triallength);
             nwin = floor(triallength/app.nsamp);
             nwinbuff = 1;
             nwinbuffmax = floor(triallength/app.nsamp);
             app.EMG = zeros(64*app.n_grids,nwin*app.nsamp);
             i = 1;
             channels = 1:1:64*app.n_grids;
             offset = ones(64*app.n_grids,app.nsamp);
             offset = offset .* channels';
             
             fwrite(app.tcpScoket,'startTX');
             app.CalibrateButton.BackgroundColor = [0 1 0]; % Turn green [If connection was established]
             pause(0.01);
             
             t = timer('ExecutionMode','fixedRate','Period',30,'TimerFcn',@(~,~)capture_data());
             start(t);
             
            function capture_data()
                signal = fread(app.tcpScoket,[app.NumChanVal(app.NCHsel), app.nsamp], 'int16')';
                disp(app.NumChanVal(app.NCHsel));
                % signal = fread(app.tcpScoket,[216, app.nsamp],'int16');
                buffer(:,(nwinbuff-1)*app.nsamp+1:nwinbuff*app.nsamp) = signal(129+4:128+4+64*app.n_grids,:) * 0.0005 + offset;
                app.EMG(:,(i-1)*app.nsamp+1:i*app.nsamp) = signal(129+4 : 128+4+64*app.n_grids, :);
                
                plot(app.UIAxes, time, buffer, 'LineWidth', 1, 'Color', 'k');
                app.UIAxes.XLim = [0 6];
                app.UIAxes.YLim = [0 130];
                line(app.UIAxes, [time(nwinbuff*app.nsamp) time(nwinbuff*app.nsamp)], [0 64], 'LineWidth', 1, 'Color', 'k');
                drawnow limitrate
                i = i + 1;
                if nwinbuff < nwinbuffmax
                    nwinbuff = nwinbuff+1;
                else
                    nwinbuff = 1;
                end
                clc
            end

%              while i <= nwin
%                  signal = fread(app.tcpScoket,[app.NumChanVal(app.NCHsel), app.nsamp], 'int16')';
%                  disp(app.NumChanVal(app.NCHsel));
%                  % signal = fread(app.tcpScoket,[216, app.nsamp],'int16');
%                  buffer(:,(nwinbuff-1)*app.nsamp+1:nwinbuff*app.nsamp) = signal(129+4:128+4+64*app.n_grids,:) * 0.0005 + offset;
%                  app.EMG(:,(i-1)*app.nsamp+1:i*app.nsamp) = signal(129+4 : 128+4+64*app.n_grids, :);
% 
%                  plot(app.UIAxes, time, buffer, 'LineWidth', 1, 'Color', 'k');
%                  app.UIAxes.XLim = [0 6];
%                  app.UIAxes.YLim = [0 130];
%                  line(app.UIAxes, [time(nwinbuff*app.nsamp) time(nwinbuff*app.nsamp)], [0 64], 'LineWidth', 1, 'Color', 'k');
%                  drawnow limitrate
%                  i = i + 1;
%                  if nwinbuff < nwinbuffmax
%                      nwinbuff = nwinbuff+1;
%                  else
%                      nwinbuff = 1;
%                  end
%                  clc
%              end

             fwrite(app.tcpScoket,'stopTX');
             fclose(app.tcpScoket);
             plot(app.UIAxes, 0, 0, 'LineWidth', 1, 'Color', 'k');
             drawnow
             %assignin("base","EMG",app.EMG)
             app.CalibrateButton.BackgroundColor = [0.96 0.96 0.96]; % Turn red [If connection was severed]
             pause(0.5);
             app.CalibrateButton.BackgroundColor = [0 1 0]; % Turn green [If connection was established]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.Panel = uipanel(app.OptionsTab);
            app.STARTSTOPACQUISITIONButton = uibutton(app.Panel, 'push');
            app.STARTSTOPACQUISITIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSTOPACQUISITIONButtonPushed, true);
            app.STARTSTOPACQUISITIONButton.Text = 'START/STOP ACQUISITION';
            app.Lamp = uilamp(app.Panel);
            app.CalibrateButton = uibutton(app.Panel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.OffsetButton = uibutton(app.Panel, 'push');
            app.OffsetButton.Text = 'Offset';
            app.Lamp_2 = uilamp(app.Panel);
            app.Lamp_3 = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.OptionsTab);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ForceTab = uitab(app.TabGroup);
            app.ForceTab.Title = 'Force';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_draft_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end